classdef COSMOSS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_Structure                 matlab.ui.container.Panel
        ListBox_Model                   matlab.ui.control.ListBox
        Button_SelectModel              matlab.ui.control.Button
        Panel_Response                  matlab.ui.container.Panel
        LocalmodeFreqDropDownLabel      matlab.ui.control.Label
        DropDown_LocFreq                matlab.ui.control.DropDown
        CouplingModelDropDownLabel      matlab.ui.control.Label
        DropDown_Coupling               matlab.ui.control.DropDown
        BetaNNcm1Label                  matlab.ui.control.Label
        EditField_NN                    matlab.ui.control.NumericEditField
        FeynmannpathwayCutoffEditFieldLabel  matlab.ui.control.Label
        EditField_FeynmannCutoff        matlab.ui.control.NumericEditField
        Panel_ExpSetup                  matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DTab_Exp1D                      matlab.ui.container.Tab
        UITable_1D                      matlab.ui.control.Table
        DTab_Exp2D                      matlab.ui.container.Tab
        UITable_2D                      matlab.ui.control.Table
        Panel_FigOpt                    matlab.ui.container.Panel
        CheckBox_SaveFig                matlab.ui.control.CheckBox
        EditField_SavePath              matlab.ui.control.EditField
        CheckBox_Sticks                 matlab.ui.control.CheckBox
        CheckBox_Normalize              matlab.ui.control.CheckBox
        CheckBox_Cursor                 matlab.ui.control.CheckBox
        FreqMinLabel                    matlab.ui.control.Label
        EditField_FreqMin               matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        EditField_FreqMax               matlab.ui.control.NumericEditField
        LineShapeLabel                  matlab.ui.control.Label
        DropDown_LineShape              matlab.ui.control.DropDown
        WidthLabel                      matlab.ui.control.Label
        EditField_Width                 matlab.ui.control.NumericEditField
        ContourEditFieldLabel           matlab.ui.control.Label
        EditField_Contour               matlab.ui.control.NumericEditField
        ColorMapDropDownLabel           matlab.ui.control.Label
        DropDown_ColorMap               matlab.ui.control.DropDown
        SignalTypeDropDownLabel         matlab.ui.control.Label
        DropDown_SignalType             matlab.ui.control.DropDown
        SpectralTypeDropDownLabel       matlab.ui.control.Label
        DropDown_SpectralType           matlab.ui.control.DropDown
        PathwayDropDownLabel            matlab.ui.control.Label
        DropDown_Pathway                matlab.ui.control.DropDown
        Panel_AnalysisTools             matlab.ui.container.Panel
        TabGroupTools                   matlab.ui.container.TabGroup
        Tab_FTIR                        matlab.ui.container.Tab
        Button_TS_FTIR                  matlab.ui.control.Button
        Tab_SFG                         matlab.ui.container.Tab
        Button_TS_SFG                   matlab.ui.control.Button
        Button_Orient_1DSFG             matlab.ui.control.Button
        ResponseVisulizationButton      matlab.ui.control.Button
        Tab_2DIR                        matlab.ui.container.Tab
        Button_TS_2DIR                  matlab.ui.control.Button
        Button_Cut_2DIR                 matlab.ui.control.Button
        Tab_2DSFG                       matlab.ui.container.Tab
        Button_Orient_2DSFG             matlab.ui.control.Button
        Button_TS_2DSFG                 matlab.ui.control.Button
        Button_Cut_2DSFG                matlab.ui.control.Button
        Button_FTIR                     matlab.ui.control.Button
        Button_SFG                      matlab.ui.control.Button
        Button_2DIR                     matlab.ui.control.Button
        Button_2DSFG                    matlab.ui.control.Button
        Button_ExportData               matlab.ui.control.Button
        Panel_SampleSymmetry            matlab.ui.container.Panel
        RotationalAverageDropDownLabel  matlab.ui.control.Label
        DropDown_RotAvg                 matlab.ui.control.DropDown
        MirrorPlaneDropDownLabel        matlab.ui.control.Label
        DropDown_MirrorPlane            matlab.ui.control.DropDown
        Panel_EnsembleAverage           matlab.ui.container.Panel
        CheckBox_Sampling               matlab.ui.control.CheckBox
        SampleEditFieldLabel            matlab.ui.control.Label
        EditField_SampleN               matlab.ui.control.NumericEditField
        CheckBox_DynamicFigUpdate       matlab.ui.control.CheckBox
        CheckBox_Continue               matlab.ui.control.CheckBox
        FluctuationCorrelationLabel     matlab.ui.control.Label
        EditField_FluctuationCorrelation  matlab.ui.control.NumericEditField
        DiagonalDisorderFWHMcm1EditFieldLabel  matlab.ui.control.Label
        EditField_DD                    matlab.ui.control.NumericEditField
        OffdiagonalDisorderFWHMcm1EditFieldLabel  matlab.ui.control.Label
        EditField_ODD                   matlab.ui.control.NumericEditField
        Version                         matlab.ui.control.Label
    end


    properties (Access = public)
        Structure  % StructureData
        GUI_Tag    % Tag for GUI recognition 
        hModel     % handle of modeling GUI
        hOrient    % handle of Oriental Analysis GUI
        hResVis    % handle of Response Visulization GUI
        
        Data_FTIR  % FTIR
        Data_SFG   % SFG
        Data_2DIR  % 2D IR
        Data_2DSFG % 2D SFG
        Refresh_FTIR  % Refresh FTIR tag
        Refresh_SFG   % Refresh SFG tag
        Refresh_2DIR  % Refresh 2DIR tag
        Refresh_2DSFG % Refresh 2DSFG tag
        RefreshSource % Refresh off trigger source
        
    end

    methods (Access = public)
    
        function RefreshOn(app)
            app.Refresh_FTIR  = 1;
            app.Refresh_SFG   = 1;
            app.Refresh_2DIR  = 1;
            app.Refresh_2DSFG = 1;
            
            app.Button_FTIR.FontColor  = [0,0,1];
            app.Button_SFG.FontColor   = [0,0,1];
            app.Button_2DIR.FontColor  = [0,0,1];
            app.Button_2DSFG.FontColor = [0,0,1];
        end
        
        function RefreshOff(app)
            switch app.RefreshSource
                case 'FTIR'
                    app.Refresh_FTIR = 0;
                    app.Button_FTIR.FontColor = [0,0,0];
                case 'SFG'
                    app.Refresh_SFG = 0;
                    app.Button_SFG.FontColor = [0,0,0];
                case '2DIR'
                    app.Refresh_2DIR = 0;
                    app.Button_2DIR.FontColor = [0,0,0];
                case '2DSFG'
                    app.Refresh_2DSFG = 0;
                    app.Button_2DSFG.FontColor = [0,0,0];
            end
        end

        function O = Parse_GUI(app)
            % Molecular response ------------------------------------------------------
            O.LocFreqType = app.DropDown_LocFreq.Value;
            
            % Coupling model
            O.CouplingType = app.DropDown_Coupling.Value;
            O.Beta_NN      = app.EditField_NN.Value;
            O.PCutOff      = app.EditField_FeynmannCutoff.Value;
            % -------------------------------------------------------------------------
            
            % For Sample Symmetry -----------------------------------------------------
            O.Avg_Rot    = app.DropDown_RotAvg.Value;
            O.Avg_Mirror = app.DropDown_MirrorPlane.Value;
            % -------------------------------------------------------------------------
            
            % Esemble average ---------------------------------------------------------
            O.Sampling      = app.CheckBox_Sampling.Value;
            O.Sample_Num    = app.EditField_SampleN.Value;
            O.DynamicUpdate = app.CheckBox_DynamicFigUpdate.Value;
            O.UpdateStatus  = app.CheckBox_Continue.Value;
            O.P_FlucCorr    = app.EditField_FluctuationCorrelation.Value;
            O.DD_FWHM       = app.EditField_DD.Value;
            O.ODD_FWHM      = app.EditField_ODD.Value;
            % -------------------------------------------------------------------------
            
            % Exp parameters 1D -------------------------------------------------------
            Exp1D = app.UITable_1D.Data;
            O.A_IR         = Exp1D(1,1);
            O.A_Vis1D      = Exp1D(1,2);
            O.A_Sig1D      = Exp1D(1,3);
            O.P_IR         = Exp1D(2,1);
            O.P_Vis1D      = Exp1D(2,2);
            O.P_Sig1D      = Exp1D(2,3);
            O.Exp1D        = Exp1D;
            % -------------------------------------------------------------------------
            
            % Exp parameters 2D -------------------------------------------------------
            Exp2D = app.UITable_2D.Data;
            O.A_Pump1      = Exp2D(1,1);
            O.A_Pump2      = Exp2D(1,2);
            O.A_Probe      = Exp2D(1,3);
            O.A_Vis2D      = Exp2D(1,4);
            O.A_Sig2D      = Exp2D(1,5);
            O.P_Pump1      = Exp2D(2,1);
            O.P_Pump2      = Exp2D(2,2);
            O.P_Probe      = Exp2D(2,3);
            O.P_Vis2D      = Exp2D(2,4);
            O.P_Sig2D      = Exp2D(2,5);
            O.Exp2D        = Exp2D;
            % -------------------------------------------------------------------------
            
            % For Figures -------------------------------------------------------------
            O.SaveFig      = app.CheckBox_SaveFig.Value;
            O.SavePath     = app.EditField_SavePath.Value;
            O.PlotStick    = app.CheckBox_Sticks.Value;
            O.PlotNorm     = app.CheckBox_Normalize.Value;
            O.PlotCursor   = app.CheckBox_Cursor.Value;
            O.F_Min        = app.EditField_FreqMin.Value;
            O.F_Max        = app.EditField_FreqMax.Value;
            O.FreqRange    = O.F_Min:O.F_Max;
            O.LineShape    = app.DropDown_LineShape.Value;
            O.LineWidth    = app.EditField_Width.Value;
            O.Num_Contour  = app.EditField_Contour.Value;
            O.CMAP_Index   = app.DropDown_ColorMap.Value;
            O.Signal_Type  = app.DropDown_SignalType.Value; % Need isolate the list of options?
            O.SpecType     = app.DropDown_SpectralType.Value; % Need isolate the list of options?
            O.Pathway      = app.DropDown_Pathway.Value; % Need isolate the list of options?
            % -------------------------------------------------------------------------
            
            % For Analysis Tools ------------------------------------------------------
            
            % -------------------------------------------------------------------------

        end
        
    end

    methods (Access = private)
    
        function Continue = CheckStructue(app)
            Continue = 1;
            if isempty(app.Structure)
                disp('Not structre model defined, run structure construction first...')
                Continue = 0;
            end
        end
        
        function Init(app)
            %% Setup paths
            addpath(pwd);
            
            MoleculeConstruction = genpath([pwd,'/MoleculeConstruction']);
            SpectralFunctions    = genpath([pwd,'/SpecFunc']);
            AnalysisTools        = genpath([pwd,'/AnalysisTools']);
            addpath(MoleculeConstruction);
            addpath(SpectralFunctions);
            addpath(AnalysisTools);
            
            % Setup the Model structure list
            [~,StructureModelList,~] = StructureModel(0);
            app.ListBox_Model.Items  = StructureModelList;
            app.ListBox_Model.ItemsData = 1:length(StructureModelList);
            
            % Setup Coupling model items
            [~,CouplingList] = Coupling('','List','');
            app.DropDown_Coupling.Items = CouplingList;
            
            % Setup Ensemble average list
            [~,~,R_List,M_List] = LabFrameAvg('List','List','');
            app.DropDown_RotAvg.Items = R_List;
            app.DropDown_MirrorPlane.Items = M_List;
            
            % Setup initial values of the experimental setups
            Exp_1D = zeros(2,3);
            Exp_1D(1,:) = 90;
            app.UITable_1D.Data = Exp_1D;
            app.UITable_1D.ColumnWidth = {40,44,40};
            app.UITable_1D.ColumnEditable = true;
            app.UITable_1D.RowName = {'Ang.';'Pol.'};
            
            Exp_2D = zeros(2,5);
            Exp_2D(1,:) = 90;
            app.UITable_2D.Data = Exp_2D;
            app.UITable_2D.ColumnWidth = {44,44,40,41,40};
            app.UITable_2D.ColumnEditable = true;
            app.UITable_2D.RowName = {'Ang.';'Pol.'};
            
            % Setup Lineshape list
            [~,AvalibleL] = Conv_LineShape('List','','','');
            app.DropDown_LineShape.Items = AvalibleL;
            
            % Setup color map list
            [~,CMap_List] = SelectColormap('List');
            app.DropDown_ColorMap.Items = CMap_List;
            
            %% Add Properties Change Listner
            SA = [...
                  app.DropDown_LocFreq;...
                  app.DropDown_Coupling;...
                  app.EditField_NN;...
                  app.EditField_FeynmannCutoff;...
                  app.DropDown_RotAvg;...
                  app.DropDown_MirrorPlane;...
                  app.CheckBox_Sampling;...
                  app.EditField_SampleN;...
                  app.CheckBox_DynamicFigUpdate;...
                  app.EditField_FluctuationCorrelation;...
                  app.EditField_DD;...
                  app.EditField_ODD;...
                  ];
            
            for i = 1:length(SA)
                SA(i).ValueChangedFcn = @(~,~)RefreshOn(app);
            end
            
            app.UITable_1D.CellEditCallback = @(~,~)RefreshOn(app);
            app.UITable_2D.CellEditCallback = @(~,~)RefreshOn(app);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GUI_Tag = 'COSMOSS';
            Init(app)
            RefreshOn(app) % Initialize refresh tags
        end

        % Button pushed function: Button_SelectModel
        function Button_SelectModelPushed(app, event)
            StructModel = app.ListBox_Model.Value;
            [fhModel,ModelList,~] = StructureModel(StructModel);
            
            app.hModel = feval(fhModel,'COSMOSS',app);
            disp(['COSMOSS using model ' ModelList{StructModel}])
        end

        % Button pushed function: Button_ExportData
        function Button_ExportDataPushed(app, event)
            Data_COSMOSS.hModel     = app.hModel;
            Data_COSMOSS.Structure  = app.Structure;
            Data_COSMOSS.Data_FTIR  = app.Data_FTIR;
            Data_COSMOSS.Data_SFG   = app.Data_SFG;
            Data_COSMOSS.Data_2DIR  = app.Data_2DIR;
            Data_COSMOSS.Data_2DSFG = app.Data_2DSFG;
            Data_COSMOSS.hCOSMOSS   = app;
            
            assignin('base', 'Data_COSMOSS', Data_COSMOSS)
            %assignin('base', 'app', app)
            disp('Updated Data_COSMOSS exported!')
        end

        % Button pushed function: Button_FTIR
        function Button_FTIRPushed(app, event)
            if CheckStructue(app)
                app.RefreshSource = 'FTIR';
                RefreshOff(app)
                
                app.TabGroup.SelectedTab = app.DTab_Exp1D;
                app.TabGroupTools.SelectedTab = app.Tab_FTIR;
                app.Data_FTIR = OneD_Iteration(@FTIR_Main,app);
                
                hF_final = figure;
                hAx_final = axes('Parent',hF_final);
                Plot1D(hAx_final,app.Data_FTIR,Parse_GUI(app));
            end
        end

        % Button pushed function: Button_SFG
        function Button_SFGPushed(app, event)
            if CheckStructue(app)
                app.RefreshSource = 'SFG';
                RefreshOff(app)
                
                app.TabGroup.SelectedTab = app.DTab_Exp1D;
                app.TabGroupTools.SelectedTab = app.Tab_SFG;
                app.Data_SFG = OneD_Iteration(@OneDSFG_Main,app);

                hF_final = figure;
                hAx_final = axes('Parent',hF_final);
                Plot1D(hAx_final,app.Data_SFG,Parse_GUI(app));
            end
        end

        % Button pushed function: Button_2DIR
        function Button_2DIRPushed(app, event)
            if CheckStructue(app)
                app.TabGroup.SelectedTab = app.DTab_Exp2D;
                app.TabGroupTools.SelectedTab = app.Tab_2DIR;
                % Determine if re-calculate
                if app.Refresh_2DIR
                    [SpectraGrid,Response] = TwoD_Iteration(@TwoDIR_Main_Sparse,app);
                    app.RefreshSource = '2DIR';
                    RefreshOff(app)
                else
                    Response    = app.Data_2DIR.Response;
                    SpectraGrid = app.Data_2DIR.SpectraGrid;
                end
                
                I = Parse_GUI(app);
                CVL = Conv2D(SpectraGrid,I);
                CVL.FilesName = app.Structure.FilesName; % pass filesname for figure title
                    
                hF_final = figure;
                hAx_final = axes('Parent',hF_final);
                Plot2D(hAx_final,CVL,I,Response.SpecType);
                
                % Output
                app.Data_2DIR.SpectraGrid = SpectraGrid;
                app.Data_2DIR.Response    = Response;
                app.Data_2DIR.CVL         = CVL;
                app.Data_2DIR.SpecType    = '2DIR';
            end
        end

        % Button pushed function: Button_2DSFG
        function Button_2DSFGPushed(app, event)
            if CheckStructue(app)
                app.TabGroup.SelectedTab = app.DTab_Exp2D;
                app.TabGroupTools.SelectedTab = app.Tab_2DSFG;
                % Determine if re-calculate
                if app.Refresh_2DSFG
                    [SpectraGrid,Response] = TwoD_Iteration(@TwoDSFG_Main_Sparse,app);
                    app.RefreshSource = '2DSFG';
                    RefreshOff(app)
                else
                    Response    = app.Data_2DSFG.Response;
                    SpectraGrid = app.Data_2DSFG.SpectraGrid;
                end
                
                I = Parse_GUI(app);
                CVL = Conv2D(SpectraGrid,I);
                CVL.FilesName = app.Structure.FilesName; % pass filesname for figure title
                
                hF_final = figure;
                hAx_final = axes('Parent',hF_final);
                Plot2D(hAx_final,CVL,I,Response.SpecType);
                
                % Output
                app.Data_2DSFG.SpectraGrid = SpectraGrid;
                app.Data_2DSFG.Response    = Response;
                app.Data_2DSFG.CVL         = CVL;
                app.Data_2DSFG.SpecType    = '2DSFG';
            end
        end

        % Button pushed function: Button_Orient_2DSFG
        function Button_Orient_2DSFGPushed(app, event)
            app.hOrient = feval(@OrientationAnalysis,'COSMOSS',app,'2DSFG');
        end

        % Button pushed function: Button_TS_FTIR
        function Button_TS_FTIRPushed(app, event)
            Transition_Strength(app.Data_FTIR)
        end

        % Button pushed function: Button_TS_SFG
        function Button_TS_SFGPushed(app, event)
            Transition_Strength(app.Data_SFG)
        end

        % Button pushed function: Button_TS_2DIR
        function Button_TS_2DIRPushed(app, event)
            Transition_Strength(app.Data_2DIR)
        end

        % Button pushed function: Button_TS_2DSFG
        function Button_TS_2DSFGPushed(app, event)
            Transition_Strength(app.Data_2DSFG)
        end

        % Button pushed function: Button_Orient_1DSFG
        function Button_Orient_1DSFGPushed(app, event)
            app.hOrient = feval(@OrientationAnalysis,'COSMOSS',app,'SFG');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            rmpath(pwd);
        end

        % Button pushed function: ResponseVisulizationButton
        function Button_ResponseVisulization(app, event)
            app.hResVis = feval(@ResVis,'COSMOSS',app,'SFG');
        end

        % Button pushed function: Button_Cut_2DIR, Button_Cut_2DSFG
        function Button_2DCut_Pushed(app, event)
            Cuts
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'COSMOSS';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_Structure = uipanel(app.UIFigure);
            app.Panel_Structure.Title = 'Build Model Structure';
            app.ListBox_Model = uilistbox(app.Panel_Structure);
            app.ListBox_Model.Items = {'Two Couple Oscillators Model', 'Amide-I modes from PDB structure', '2D Grid from formatted ab initio output', 'Amide-I of ideal beta-sheet', 'Combination of any two model above'};
            app.ListBox_Model.ItemsData = {'1', '2', '3', '4', '5'};
            app.ListBox_Model.Multiselect = 'on';
            app.ListBox_Model.Value = {'1'};
            app.Button_SelectModel = uibutton(app.Panel_Structure, 'push');
            app.Button_SelectModel.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectModelPushed, true);
            app.Button_SelectModel.Text = 'Constructing structural model';
            app.Panel_Response = uipanel(app.UIFigure);
            app.Panel_Response.Title = 'Molecular Response';
            app.LocalmodeFreqDropDownLabel = uilabel(app.Panel_Response);
            app.LocalmodeFreqDropDownLabel.Text = 'Local mode Freq.';
            app.DropDown_LocFreq = uidropdown(app.Panel_Response);
            app.DropDown_LocFreq.Items = {'Same', 'Jensen Map'};
            app.DropDown_LocFreq.Value = 'Same';
            app.CouplingModelDropDownLabel = uilabel(app.Panel_Response);
            app.CouplingModelDropDownLabel.Text = 'Coupling Model';
            app.DropDown_Coupling = uidropdown(app.Panel_Response);
            app.DropDown_Coupling.Items = {'TDC', 'NN + TDC', 'Jensen Map + TDC', 'Zero'};
            app.DropDown_Coupling.Value = 'TDC';
            app.BetaNNcm1Label = uilabel(app.Panel_Response);
            app.BetaNNcm1Label.Text = 'Beta NN (cm-1)';
            app.EditField_NN = uieditfield(app.Panel_Response, 'numeric');
            app.EditField_NN.Value = 0.8;
            app.FeynmannpathwayCutoffEditFieldLabel = uilabel(app.Panel_Response);
            app.FeynmannpathwayCutoffEditFieldLabel.Text = 'Feynmann pathway Cutoff';
            app.EditField_FeynmannCutoff = uieditfield(app.Panel_Response, 'numeric');
            app.Panel_ExpSetup = uipanel(app.UIFigure);
            app.Panel_ExpSetup.Title = 'Experimental Setting';
            app.TabGroup = uitabgroup(app.Panel_ExpSetup);
            app.DTab_Exp1D = uitab(app.TabGroup);
            app.DTab_Exp1D.Title = '1D';
            app.UITable_1D = uitable(app.DTab_Exp1D);
            app.UITable_1D.ColumnName = {'IR'; 'Visible'; 'Signal'};
            app.UITable_1D.RowName = {};
            app.UITable_1D.ColumnEditable = [false false true];
            app.DTab_Exp2D = uitab(app.TabGroup);
            app.DTab_Exp2D.Title = '2D';
            app.UITable_2D = uitable(app.DTab_Exp2D);
            app.UITable_2D.ColumnName = {'Pump1'; 'Pump2'; 'Probe'; 'Visible'; 'Signal'};
            app.UITable_2D.RowName = {};
            app.Panel_FigOpt = uipanel(app.UIFigure);
            app.Panel_FigOpt.Title = 'Figure Setting';
            app.CheckBox_SaveFig = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_SaveFig.Text = 'Save Fig to: ';
            app.EditField_SavePath = uieditfield(app.Panel_FigOpt, 'text');
            app.EditField_SavePath.Value = '~/Desktop/TMP';
            app.CheckBox_Sticks = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Sticks.Text = 'Sticks';
            app.CheckBox_Sticks.Value = true;
            app.CheckBox_Normalize = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Normalize.Text = 'Normalize';
            app.CheckBox_Normalize.Value = true;
            app.CheckBox_Cursor = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Cursor.Text = 'Cursor';
            app.CheckBox_Cursor.Value = true;
            app.FreqMinLabel = uilabel(app.Panel_FigOpt);
            app.FreqMinLabel.Text = 'Freq. Min:';
            app.EditField_FreqMin = uieditfield(app.Panel_FigOpt, 'numeric');
            app.EditField_FreqMin.Value = 1600;
            app.MaxLabel = uilabel(app.Panel_FigOpt);
            app.MaxLabel.Text = 'Max:';
            app.EditField_FreqMax = uieditfield(app.Panel_FigOpt, 'numeric');
            app.EditField_FreqMax.Value = 1750;
            app.LineShapeLabel = uilabel(app.Panel_FigOpt);
            app.LineShapeLabel.Text = 'Line Shape:';
            app.DropDown_LineShape = uidropdown(app.Panel_FigOpt);
            app.DropDown_LineShape.Items = {'Lorentzian', 'Gaussian', 'No Conv', 'Spy'};
            app.DropDown_LineShape.Value = 'Lorentzian';
            app.WidthLabel = uilabel(app.Panel_FigOpt);
            app.WidthLabel.Text = 'Width:';
            app.EditField_Width = uieditfield(app.Panel_FigOpt, 'numeric');
            app.EditField_Width.Value = 5;
            app.ContourEditFieldLabel = uilabel(app.Panel_FigOpt);
            app.ContourEditFieldLabel.Text = 'Contour#:';
            app.EditField_Contour = uieditfield(app.Panel_FigOpt, 'numeric');
            app.EditField_Contour.Value = 20;
            app.ColorMapDropDownLabel = uilabel(app.Panel_FigOpt);
            app.ColorMapDropDownLabel.Text = 'Color Map:';
            app.DropDown_ColorMap = uidropdown(app.Panel_FigOpt);
            app.SignalTypeDropDownLabel = uilabel(app.Panel_FigOpt);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.DropDown_SignalType = uidropdown(app.Panel_FigOpt);
            app.DropDown_SignalType.Items = {'Heterodyne', 'Homodyne'};
            app.DropDown_SignalType.Value = 'Heterodyne';
            app.SpectralTypeDropDownLabel = uilabel(app.Panel_FigOpt);
            app.SpectralTypeDropDownLabel.Text = 'Spectral Type';
            app.DropDown_SpectralType = uidropdown(app.Panel_FigOpt);
            app.DropDown_SpectralType.Items = {'Absorptive', 'Rephasing', 'Non-rephasing'};
            app.DropDown_SpectralType.Value = 'Absorptive';
            app.PathwayDropDownLabel = uilabel(app.Panel_FigOpt);
            app.PathwayDropDownLabel.Text = 'Pathway';
            app.DropDown_Pathway = uidropdown(app.Panel_FigOpt);
            app.DropDown_Pathway.Items = {'All', 'GB', 'SE', 'EA'};
            app.DropDown_Pathway.Value = 'All';
            app.Panel_AnalysisTools = uipanel(app.UIFigure);
            app.Panel_AnalysisTools.Title = 'Analysis Tools';
            app.TabGroupTools = uitabgroup(app.Panel_AnalysisTools);
            app.Tab_FTIR = uitab(app.TabGroupTools);
            app.Tab_FTIR.Title = 'FTIR';
            app.Button_TS_FTIR = uibutton(app.Tab_FTIR, 'push');
            app.Button_TS_FTIR.ButtonPushedFcn = createCallbackFcn(app, @Button_TS_FTIRPushed, true);
            app.Button_TS_FTIR.Text = 'Transition Strength';
            app.Tab_SFG = uitab(app.TabGroupTools);
            app.Tab_SFG.Title = 'SFG';
            app.Button_TS_SFG = uibutton(app.Tab_SFG, 'push');
            app.Button_TS_SFG.ButtonPushedFcn = createCallbackFcn(app, @Button_TS_SFGPushed, true);
            app.Button_TS_SFG.Text = 'Transition Strength';
            app.Button_Orient_1DSFG = uibutton(app.Tab_SFG, 'push');
            app.Button_Orient_1DSFG.ButtonPushedFcn = createCallbackFcn(app, @Button_Orient_1DSFGPushed, true);
            app.Button_Orient_1DSFG.Text = 'Orientation Analysis';
            app.ResponseVisulizationButton = uibutton(app.Tab_SFG, 'push');
            app.ResponseVisulizationButton.ButtonPushedFcn = createCallbackFcn(app, @Button_ResponseVisulization, true);
            app.ResponseVisulizationButton.Text = 'Response Visulization';
            app.Tab_2DIR = uitab(app.TabGroupTools);
            app.Tab_2DIR.Title = '2D IR';
            app.Button_TS_2DIR = uibutton(app.Tab_2DIR, 'push');
            app.Button_TS_2DIR.ButtonPushedFcn = createCallbackFcn(app, @Button_TS_2DIRPushed, true);
            app.Button_TS_2DIR.Text = 'Transition Strength';
            app.Button_Cut_2DIR = uibutton(app.Tab_2DIR, 'push');
            app.Button_Cut_2DIR.ButtonPushedFcn = createCallbackFcn(app, @Button_2DCut_Pushed, true);
            app.Button_Cut_2DIR.Text = '2D Cut';
            app.Tab_2DSFG = uitab(app.TabGroupTools);
            app.Tab_2DSFG.Title = '2D SFG';
            app.Button_Orient_2DSFG = uibutton(app.Tab_2DSFG, 'push');
            app.Button_Orient_2DSFG.ButtonPushedFcn = createCallbackFcn(app, @Button_Orient_2DSFGPushed, true);
            app.Button_Orient_2DSFG.Text = 'Orientation Analysis';
            app.Button_TS_2DSFG = uibutton(app.Tab_2DSFG, 'push');
            app.Button_TS_2DSFG.ButtonPushedFcn = createCallbackFcn(app, @Button_TS_2DSFGPushed, true);
            app.Button_TS_2DSFG.Text = 'Transition Strength';
            app.Button_Cut_2DSFG = uibutton(app.Tab_2DSFG, 'push');
            app.Button_Cut_2DSFG.ButtonPushedFcn = createCallbackFcn(app, @Button_2DCut_Pushed, true);
            app.Button_Cut_2DSFG.Text = '2D Cut';
            app.Button_FTIR = uibutton(app.UIFigure, 'push');
            app.Button_FTIR.ButtonPushedFcn = createCallbackFcn(app, @Button_FTIRPushed, true);
            app.Button_FTIR.Text = 'FTIR';
            app.Button_SFG = uibutton(app.UIFigure, 'push');
            app.Button_SFG.ButtonPushedFcn = createCallbackFcn(app, @Button_SFGPushed, true);
            app.Button_SFG.Text = 'SFG';
            app.Button_2DIR = uibutton(app.UIFigure, 'push');
            app.Button_2DIR.ButtonPushedFcn = createCallbackFcn(app, @Button_2DIRPushed, true);
            app.Button_2DIR.Text = '2D IR';
            app.Button_2DSFG = uibutton(app.UIFigure, 'push');
            app.Button_2DSFG.ButtonPushedFcn = createCallbackFcn(app, @Button_2DSFGPushed, true);
            app.Button_2DSFG.Text = '2D SFG';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportDataPushed, true);
            app.Button_ExportData.Text = 'Export Data';
            app.Panel_SampleSymmetry = uipanel(app.UIFigure);
            app.Panel_SampleSymmetry.Title = 'Sample Symmetry';
            app.RotationalAverageDropDownLabel = uilabel(app.Panel_SampleSymmetry);
            app.RotationalAverageDropDownLabel.Text = 'Rotational Average';
            app.DropDown_RotAvg = uidropdown(app.Panel_SampleSymmetry);
            app.DropDown_RotAvg.Items = {'Phi', 'Psi', 'Phi,Psi', 'Isotropic', 'C1'};
            app.DropDown_RotAvg.Value = 'Phi';
            app.MirrorPlaneDropDownLabel = uilabel(app.Panel_SampleSymmetry);
            app.MirrorPlaneDropDownLabel.Text = 'Mirror Plane';
            app.DropDown_MirrorPlane = uidropdown(app.Panel_SampleSymmetry);
            app.DropDown_MirrorPlane.Items = {'No', 'Signam V'};
            app.DropDown_MirrorPlane.Value = 'No';
            app.Panel_EnsembleAverage = uipanel(app.UIFigure);
            app.Panel_EnsembleAverage.Title = 'Ensemble Average';
            app.CheckBox_Sampling = uicheckbox(app.Panel_EnsembleAverage);
            app.CheckBox_Sampling.Text = 'Random Sampling';
            app.SampleEditFieldLabel = uilabel(app.Panel_EnsembleAverage);
            app.SampleEditFieldLabel.Text = 'Sample #';
            app.EditField_SampleN = uieditfield(app.Panel_EnsembleAverage, 'numeric');
            app.EditField_SampleN.Value = 1;
            app.CheckBox_DynamicFigUpdate = uicheckbox(app.Panel_EnsembleAverage);
            app.CheckBox_DynamicFigUpdate.Text = 'Dynamic Fig Update';
            app.CheckBox_Continue = uicheckbox(app.Panel_EnsembleAverage);
            app.CheckBox_Continue.Text = 'Continue?';
            app.FluctuationCorrelationLabel = uilabel(app.Panel_EnsembleAverage);
            app.FluctuationCorrelationLabel.Text = 'Fluctuation Correlation (%)';
            app.EditField_FluctuationCorrelation = uieditfield(app.Panel_EnsembleAverage, 'numeric');
            app.EditField_FluctuationCorrelation.Value = 100;
            app.DiagonalDisorderFWHMcm1EditFieldLabel = uilabel(app.Panel_EnsembleAverage);
            app.DiagonalDisorderFWHMcm1EditFieldLabel.Text = 'Diagonal Disorder FWHM (cm-1)';
            app.EditField_DD = uieditfield(app.Panel_EnsembleAverage, 'numeric');
            app.EditField_DD.Value = 10;
            app.OffdiagonalDisorderFWHMcm1EditFieldLabel = uilabel(app.Panel_EnsembleAverage);
            app.OffdiagonalDisorderFWHMcm1EditFieldLabel.Text = 'Off-diagonal Disorder FWHM (cm-1)';
            app.EditField_ODD = uieditfield(app.Panel_EnsembleAverage, 'numeric');
            app.EditField_ODD.Value = 5;
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'V 2.1.3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = COSMOSS

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end