classdef Model_Betasheet_AmideI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_BSheet                    matlab.ui.container.Panel
        StrandTypeDropDownLabel         matlab.ui.control.Label
        SheetType                       matlab.ui.control.DropDown
        residuesEditFieldLabel          matlab.ui.control.Label
        N_Residue                       matlab.ui.control.NumericEditField
        strandsEditFieldLabel           matlab.ui.control.Label
        N_Strand                        matlab.ui.control.NumericEditField
        UITable_Dist                    matlab.ui.control.Table
        UITable_Angle                   matlab.ui.control.Table
        Panel_Freq                      matlab.ui.container.Panel
        LocalmodeFreqEditFieldLabel     matlab.ui.control.Label
        LocFreq                         matlab.ui.control.NumericEditField
        LabeledFreq                     matlab.ui.control.Label
        LabelFreq                       matlab.ui.control.NumericEditField
        AnharmonicityLabel              matlab.ui.control.Label
        Anharm                          matlab.ui.control.NumericEditField
        LabelIndex                      matlab.ui.control.Label
        LabelInd                        matlab.ui.control.EditField
        DiagonalDisorderEditFieldLabel  matlab.ui.control.Label
        DiagDisorder                    matlab.ui.control.NumericEditField
        OffDiagonalDisorderEditFieldLabel  matlab.ui.control.Label
        OffDiagDisorder                 matlab.ui.control.NumericEditField
        Panel_FigOpt                    matlab.ui.container.Panel
        CheckBox_Atom                   matlab.ui.control.CheckBox
        CheckBox_Bond                   matlab.ui.control.CheckBox
        CheckBox_Axis                   matlab.ui.control.CheckBox
        UpdateBSheetButton              matlab.ui.control.Button
        DrawButton                      matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        Version                         matlab.ui.control.Label
    end


    properties (Access = public)
        Parent    % handles of parent
        Structure % molecule structre class % Description
        CombOrder % The sequence number order from comb2
        GUI_Tag   % Tag for GUI recognition
    end

    methods (Access = private)
        
        function Init(app)
            app.GUI_Tag = 'BSheet';
            Dist_Table = {'Strand trans.',0,0,4.75};
            Dist_ColumnName     = {'Angstron',   'X',   'Y',    'Z'};
            Dist_ColumnFormat   = {    'char','bank','bank', 'bank'};
            Dist_ColumnEditable = [     false,  true,  true,   true];
            Dist_ColumnWidth    = {        80,    45,    45,     45};
            
            app.UITable_Dist.Data           = Dist_Table;
            app.UITable_Dist.ColumnName     = Dist_ColumnName;
            app.UITable_Dist.ColumnFormat   = Dist_ColumnFormat;
            app.UITable_Dist.ColumnEditable = Dist_ColumnEditable;
            app.UITable_Dist.ColumnWidth    = Dist_ColumnWidth;
            
            Angle_Table = {'Strand twist',0,0,0;...
                           'Lab rotation',0,0,0};
            Angle_ColumnName     = {  'Degree', 'Phi', 'Psi','Theta'};
            Angle_ColumnFormat   = {    'char','bank','bank', 'bank'};
            Angle_ColumnEditable = [     false,  true,  true,   true];
            Angle_ColumnWidth    = {        80,    45,    45,     45};
            
            app.UITable_Angle.Data           = Angle_Table;
            app.UITable_Angle.ColumnName     = Angle_ColumnName;
            app.UITable_Angle.ColumnFormat   = Angle_ColumnFormat;
            app.UITable_Angle.ColumnEditable = Angle_ColumnEditable;
            app.UITable_Angle.ColumnWidth    = Angle_ColumnWidth;
            
                        %% Add Properties Change Listner
            SA = [...
                  app.SheetType,...
                  app.N_Residue,...
                  app.N_Strand,...
                  app.LocFreq,...
                  app.LabelFreq,...
                  app.LabelInd,...
                  app.Anharm,...
                  app.DiagDisorder,...
                  app.OffDiagDisorder,...
                  app.CheckBox_Atom,...
                  app.CheckBox_Bond,...
                  app.CheckBox_Axis,...
                  ];
            
            for i = 1:length(SA)
                SA(i).ValueChangedFcn = @(~,~)RefreshSD(app);
            end
            
            app.UITable_Dist.CellEditCallback  = @(~,~)RefreshSD(app);
            app.UITable_Angle.CellEditCallback = @(~,~)RefreshSD(app);
            
        end
        
    end
    
    methods (Access = public)

        function O = Parse_GUI(app)
            % Orientation -------------------------------------------------------------
            O.SheetType = app.SheetType.Value;
            O.N_Residue = app.N_Residue.Value;
            O.N_Strand  = app.N_Strand.Value ;
            
            Table_Dist  = app.UITable_Dist.Data;
            O.Trans_X   = Table_Dist{1,2};
            O.Trans_Y   = Table_Dist{1,3};
            O.Trans_Z   = Table_Dist{1,4};
            
            Table_Angle = app.UITable_Angle.Data;
            O.Twist_X   = Table_Angle{1,2};
            O.Twist_Y   = Table_Angle{1,3};
            O.Twist_Z   = Table_Angle{1,4};
            O.Phi_D     = Table_Angle{2,2};
            O.Psi_D     = Table_Angle{2,3};
            O.Theta_D   = Table_Angle{2,4};
                    
            % For Local Mode frequencies ----------------------
            O.NLFreq          = app.LocFreq.Value;
            O.LFreq           = app.LabelFreq.Value;
            O.Anharm          = app.Anharm.Value;
            O.L_Index         = str2num(app.LabelInd.Value);
            O.DiagDisorder    = app.DiagDisorder.Value;
            O.OffDiagDisorder = app.OffDiagDisorder.Value;
            % -------------------------------------------------
            
            % For molecule ploting ----------------------------
            O.Plot_Atoms     = app.CheckBox_Atom.Value;
            O.Plot_Bonds     = app.CheckBox_Bond.Value;
            O.Plot_Axis      = app.CheckBox_Axis.Value;
            % -------------------------------------------------
        end
        
        function RefreshSD(app)
            UpdateBSheetButtonPushed(app,'');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                ParentInfo = varargin;
            else
                ParentInfo = {'StandAlong'};
            end
            AssignParent(app,ParentInfo)
            
            % initialize the orientation table
            Init(app)
        end

        % Button pushed function: UpdateBSheetButton
        function UpdateBSheetButtonPushed(app, event)
            GUI_Inputs = Parse_GUI(app);
            
            %% Construct molecule
            S_BSheet = ConstuctBetaSheet(GUI_Inputs);
            S_BSheet = SD_GetAmideI(S_BSheet);
                              
            %% Update the frequency information
            S_BSheet.LocAnharm       = ones(S_BSheet.Nmodes,1).*GUI_Inputs.Anharm;
            S_BSheet.LocFreq         = ones(S_BSheet.Nmodes,1).*GUI_Inputs.NLFreq;
            S_BSheet.LocFreq(GUI_Inputs.L_Index) = GUI_Inputs.LFreq;
            S_BSheet.DiagDisorder    = ones(S_BSheet.Nmodes,1).*GUI_Inputs.DiagDisorder;
            S_BSheet.OffDiagDisorder = ones(S_BSheet.Nmodes,1).*GUI_Inputs.OffDiagDisorder;
            
            %% Rotate the molecule
            Phi   = GUI_Inputs.Phi_D/180*pi;
            Psi   = GUI_Inputs.Psi_D/180*pi;
            Theta = GUI_Inputs.Theta_D/180*pi;
            R = R1_ZYZ_0(Phi,Psi,Theta);
            
            BSheet = SD_Rot(S_BSheet,R);
            BSheet.StructModel = 4; % Export into Structure so it can be passsed around different GUIs
            %% Export extra info into Structure
            BSheet.Extra.RotV = [GUI_Inputs.Phi_D,GUI_Inputs.Psi_D,GUI_Inputs.Theta_D];
            
            % export necessary handle and functions
            BSheet.hPlotFunc  = @Plot_Betasheet_AmideI;
            BSheet.GUI_Inputs = GUI_Inputs;
            
            %% Export result to Main guidata
            app.Structure = BSheet;

            % update to other GUIs
            Export2Parent(app)
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            hF = app.Structure.Draw;
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            ExportGUIData(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Model Betasheet';
            app.Panel_BSheet = uipanel(app.UIFigure);
            app.Panel_BSheet.Title = 'Betasheet parameters';
            app.StrandTypeDropDownLabel = uilabel(app.Panel_BSheet);
            app.StrandTypeDropDownLabel.Text = 'Strand Type';
            app.SheetType = uidropdown(app.Panel_BSheet);
            app.SheetType.Items = {'Parallel', 'Anti-Parallel'};
            app.SheetType.Value = 'Parallel';
            app.residuesEditFieldLabel = uilabel(app.Panel_BSheet);
            app.residuesEditFieldLabel.Text = '# residues';
            app.N_Residue = uieditfield(app.Panel_BSheet, 'numeric');
            app.N_Residue.Value = 5;
            app.strandsEditFieldLabel = uilabel(app.Panel_BSheet);
            app.strandsEditFieldLabel.Text = '# strands';
            app.N_Strand = uieditfield(app.Panel_BSheet, 'numeric');
            app.N_Strand.Value = 3;
            app.UITable_Dist = uitable(app.Panel_BSheet);
            app.UITable_Dist.ColumnName = {'Distance'; 'X'; 'Y'; 'Z'};
            app.UITable_Dist.RowName = {};
            app.UITable_Dist.ColumnEditable = [false true true true];
            app.UITable_Angle = uitable(app.Panel_BSheet);
            app.UITable_Angle.ColumnName = {'Angle'; 'Phi'; 'Psi'; 'Theta'};
            app.UITable_Angle.RowName = {};
            app.UITable_Angle.ColumnEditable = [false true true true];
            app.Panel_Freq = uipanel(app.UIFigure);
            app.Panel_Freq.Title = 'Freq. & Anharmonicy (cm-1)';
            app.LocalmodeFreqEditFieldLabel = uilabel(app.Panel_Freq);
            app.LocalmodeFreqEditFieldLabel.Text = 'Local mode Freq.:';
            app.LocFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.LocFreq.Value = 1650;
            app.LabeledFreq = uilabel(app.Panel_Freq);
            app.LabeledFreq.Text = 'Labeled Freq.:';
            app.LabelFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.LabelFreq.Value = 1630;
            app.AnharmonicityLabel = uilabel(app.Panel_Freq);
            app.AnharmonicityLabel.Text = 'Anharmonicity:';
            app.Anharm = uieditfield(app.Panel_Freq, 'numeric');
            app.Anharm.Value = 10;
            app.LabelIndex = uilabel(app.Panel_Freq);
            app.LabelIndex.Text = 'Label Index:';
            app.LabelInd = uieditfield(app.Panel_Freq, 'text');
            app.DiagonalDisorderEditFieldLabel = uilabel(app.Panel_Freq);
            app.DiagonalDisorderEditFieldLabel.Text = 'Diagonal Disorder:';
            app.DiagDisorder = uieditfield(app.Panel_Freq, 'numeric');
            app.OffDiagonalDisorderEditFieldLabel = uilabel(app.Panel_Freq);
            app.OffDiagonalDisorderEditFieldLabel.Text = 'Off-Diagonal Disorder:';
            app.OffDiagDisorder = uieditfield(app.Panel_Freq, 'numeric');
            app.Panel_FigOpt = uipanel(app.UIFigure);
            app.Panel_FigOpt.Title = 'Figure options';
            app.CheckBox_Atom = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Atom.Text = 'Atom';
            app.CheckBox_Atom.Value = true;
            app.CheckBox_Bond = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Bond.Text = 'Bond';
            app.CheckBox_Bond.Value = true;
            app.CheckBox_Axis = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Axis.Text = 'Axis';
            app.CheckBox_Axis.Value = true;
            app.UpdateBSheetButton = uibutton(app.UIFigure, 'push');
            app.UpdateBSheetButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateBSheetButtonPushed, true);
            app.UpdateBSheetButton.Text = 'Update BSheet';
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'V1.2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Model_Betasheet_AmideI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end