classdef Model_Comb2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel_ModelSelection      matlab.ui.container.Panel
        stModelDropDownLabel      matlab.ui.control.Label
        DropDown_Model1           matlab.ui.control.DropDown
        ndModelDropDownLabel      matlab.ui.control.Label
        DropDown_Model2           matlab.ui.control.DropDown
        Button_Model1             matlab.ui.control.Button
        Button_Model2             matlab.ui.control.Button
        Panel_RelativeParameters  matlab.ui.container.Panel
        TransXLabel               matlab.ui.control.Label
        EditField_Trans_X         matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        EditField_Trans_Y         matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        EditField_Trans_Z         matlab.ui.control.NumericEditField
        Label_Ang                 matlab.ui.control.Label
        Label_Deg                 matlab.ui.control.Label
        RotPhiEditFieldLabel      matlab.ui.control.Label
        EditField_Rot_Phi         matlab.ui.control.NumericEditField
        PsiEditFieldLabel         matlab.ui.control.Label
        EditField_Rot_Psi         matlab.ui.control.NumericEditField
        ThetaEditFieldLabel       matlab.ui.control.Label
        EditField_Rot_Theta       matlab.ui.control.NumericEditField
        Button_Combine            matlab.ui.control.Button
        Button_Draw               matlab.ui.control.Button
        Button_ExportData         matlab.ui.control.Button
        Button_LoadMAT            matlab.ui.control.Button
        Button_SaveMAT            matlab.ui.control.Button
        Version                   matlab.ui.control.Label
    end

    properties (Access = public)
        Parent % handle of parent GUI
        Structure % generated strucutre
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                AssignParent(app,varargin)
            else
                disp('Running Model_Comb2 in the stand alone mode...')
            end
            
            % Setup the Model structure list
            [~,StructureModelList,~] = StructureModel(0);
            app.DropDown_Model1.Items = StructureModelList;
            app.DropDown_Model1.ItemsData = 1:length(StructureModelList);
            
            app.DropDown_Model2.Items = StructureModelList;
            app.DropDown_Model2.ItemsData = 1:length(StructureModelList);
        end

        % Button pushed function: Button_Model1
        function Button_Model1Pushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel_ModelSelection = uipanel(app.UIFigure);
            app.Panel_ModelSelection.Title = 'Model Selection';
            app.stModelDropDownLabel = uilabel(app.Panel_ModelSelection);
            app.stModelDropDownLabel.Text = '1st Model';
            app.DropDown_Model1 = uidropdown(app.Panel_ModelSelection);
            app.ndModelDropDownLabel = uilabel(app.Panel_ModelSelection);
            app.ndModelDropDownLabel.Text = '2nd Model';
            app.DropDown_Model2 = uidropdown(app.Panel_ModelSelection);
            app.Button_Model1 = uibutton(app.Panel_ModelSelection, 'push');
            app.Button_Model1.ButtonPushedFcn = createCallbackFcn(app, @Button_Model1Pushed, true);
            app.Button_Model1.Text = 'Model 1';
            app.Button_Model2 = uibutton(app.Panel_ModelSelection, 'push');
            app.Button_Model2.Text = 'Model 2';
            app.Panel_RelativeParameters = uipanel(app.UIFigure);
            app.Panel_RelativeParameters.Title = 'Relative parameters of the 2nd molecule';
            app.TransXLabel = uilabel(app.Panel_RelativeParameters);
            app.TransXLabel.Text = 'Trans.   X';
            app.EditField_Trans_X = uieditfield(app.Panel_RelativeParameters, 'numeric');
            app.YEditFieldLabel = uilabel(app.Panel_RelativeParameters);
            app.YEditFieldLabel.Text = 'Y';
            app.EditField_Trans_Y = uieditfield(app.Panel_RelativeParameters, 'numeric');
            app.ZEditFieldLabel = uilabel(app.Panel_RelativeParameters);
            app.ZEditFieldLabel.Text = 'Z';
            app.EditField_Trans_Z = uieditfield(app.Panel_RelativeParameters, 'numeric');
            app.Label_Ang = uilabel(app.Panel_RelativeParameters);
            app.Label_Ang.Text = 'Ang.';
            app.Label_Deg = uilabel(app.Panel_RelativeParameters);
            app.Label_Deg.Text = 'Deg.';
            app.RotPhiEditFieldLabel = uilabel(app.Panel_RelativeParameters);
            app.RotPhiEditFieldLabel.Text = 'Rot.   Phi';
            app.EditField_Rot_Phi = uieditfield(app.Panel_RelativeParameters, 'numeric');
            app.PsiEditFieldLabel = uilabel(app.Panel_RelativeParameters);
            app.PsiEditFieldLabel.Text = 'Psi';
            app.EditField_Rot_Psi = uieditfield(app.Panel_RelativeParameters, 'numeric');
            app.ThetaEditFieldLabel = uilabel(app.Panel_RelativeParameters);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.EditField_Rot_Theta = uieditfield(app.Panel_RelativeParameters, 'numeric');
            app.Button_Combine = uibutton(app.UIFigure, 'push');
            app.Button_Combine.Text = 'Combine';
            app.Button_Draw = uibutton(app.UIFigure, 'push');
            app.Button_Draw.Text = 'Draw';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.Text = 'Export Data';
            app.Button_LoadMAT = uibutton(app.UIFigure, 'push');
            app.Button_LoadMAT.Text = 'Load MAT file';
            app.Button_SaveMAT = uibutton(app.UIFigure, 'push');
            app.Button_SaveMAT.Text = 'Save MAT file';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'V 1.0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Model_Comb2(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end