classdef Model_TCO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel_Sturcture        matlab.ui.container.Panel
        UITable                matlab.ui.control.Table
        OverallrotPhiLabel     matlab.ui.control.Label
        Phi                    matlab.ui.control.NumericEditField
        PsiLabel               matlab.ui.control.Label
        Psi                    matlab.ui.control.NumericEditField
        ThetaLabel             matlab.ui.control.Label
        Theta                  matlab.ui.control.NumericEditField
        Panel_FigOpt           matlab.ui.container.Panel
        CheckBox_Atom          matlab.ui.control.CheckBox
        CheckBox_Bond          matlab.ui.control.CheckBox
        CheckBox_Axis          matlab.ui.control.CheckBox
        Button_Generate        matlab.ui.control.Button
        Button_Draw            matlab.ui.control.Button
        Button_ExportData      matlab.ui.control.Button
        Version                matlab.ui.control.Label
        Panel_Freq             matlab.ui.container.Panel
        LabeledFreqcm1         matlab.ui.control.Label
        LabelFreq              matlab.ui.control.NumericEditField
        LabelIndex             matlab.ui.control.Label
        LabelInd               matlab.ui.control.EditField
        ModeFreqcm1Label       matlab.ui.control.Label
        LocFreq                matlab.ui.control.NumericEditField
        FreqModificationLabel  matlab.ui.control.Label
        DropDown_LocFreq       matlab.ui.control.DropDown
        Anharmonicitycm1Label  matlab.ui.control.Label
        Anharm                 matlab.ui.control.NumericEditField
        Panel_Coupling         matlab.ui.container.Panel
        ModelLabel             matlab.ui.control.Label
        DropDown_Coupling      matlab.ui.control.DropDown
        BetaNNcm1Label         matlab.ui.control.Label
        EditField_NN           matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        Parent    % handles of parent
        Structure % StructureData Class 
        CombOrder % The sequence number order from comb2
        GUI_Tag   % Tag for GUI recognition 
    end

    methods (Access = private)
    
        function Init(app)
            app.GUI_Tag = 'TCO';
            %% Initial vlues of the Orientation inputs
            Orientation = zeros(3,7);
            Orientation(1:2,1) = true;
            Orientation(2,5) = 3.5;
            Orientation(1,4) = -30;
            Orientation(2,4) = 30;
            
            ColumnFormat   = {'logical','bank','bank', 'bank','bank','bank','bank'};

            app.UITable.Data           = Orientation;
            app.UITable.ColumnFormat   = ColumnFormat;
            
            % Setup Coupling model items
            [~,CouplingList] = Coupling('','List','');
            app.DropDown_Coupling.Items = CouplingList;
            
            %% Add Properties Change Listner
            SA = [...
                  app.Phi,...
                  app.Psi,...
                  app.Theta,...
                  app.CheckBox_Atom,...
                  app.CheckBox_Bond,...
                  app.CheckBox_Axis,...
                  app.LocFreq,...
                  app.LabelFreq,...
                  app.LabelInd,...
                  app.Anharm,...
                  app.DropDown_LocFreq,...
                  app.DropDown_Coupling,...
                  app.EditField_NN,...
                  ];
            
            for i = 1:length(SA)
                SA(i).ValueChangedFcn = @(~,~)RefreshSD(app);
            end
            
            app.UITable.CellEditCallback = @(~,~)RefreshSD(app);
        end

    end

    methods (Access = public)

        function O = Parse_GUI(app)
            % Read data from Table
            Table = app.UITable.Data;
            StructurePara = Table(:,2:end);
            
            O.Phi_D1   = StructurePara(1,1);
            O.Psi_D1   = StructurePara(1,2);
            O.Theta_D1 = StructurePara(1,3);
            
            O.Phi_D2   = StructurePara(2,1);
            O.Psi_D2   = StructurePara(2,2);
            O.Theta_D2 = StructurePara(2,3);
            
            O.Displacement = StructurePara(2,4:6);
            
            % Read orientation GUI inputs
            O.Phi   = app.Phi.Value;
            O.Psi   = app.Psi.Value;
            O.Theta = app.Theta.Value;
            
            % For Local Mode frequencies ----------------------------------------------
            O.NLFreq          = app.LocFreq.Value;
            O.LFreq           = app.LabelFreq.Value;
            O.Anharm          = app.Anharm.Value;
            O.L_Index         = str2num(app.LabelInd.Value);
            
            % Molecular response ------------------------------------------------------
            O.LocFreqType = app.DropDown_LocFreq.Value;
            
            % Coupling model
            O.CouplingType = app.DropDown_Coupling.Value;
            O.Beta_NN      = app.EditField_NN.Value;
            
            % For molecule ploting ----------------------------------------------------
            O.Plot_Atoms = app.CheckBox_Atom.Value;
            O.Plot_Bonds = app.CheckBox_Bond.Value;
            O.Plot_Axis  = app.CheckBox_Axis.Value; 
        end
                
        function RefreshSD(app)
            Button_GeneratePushed(app,'')
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                ParentInfo = varargin;
            else
                ParentInfo = {'StandAlong'};
            end
            AssignParent(app,ParentInfo)
            
            % initialize the orientation table
            Init(app)
        end

        % Button pushed function: Button_ExportData
        function Button_ExportDataPushed(app, event)
            ExportGUIData(app);
        end

        % Button pushed function: Button_Generate
        function Button_GeneratePushed(app, event)
            %% Read GUI variables
            GUI_Inputs = Parse_GUI(app);
            
            %% Construct molecule
            TCO = GetAcid(GUI_Inputs);
            
            % Move the CoM to origin
            Mode_Center = mean(TCO.LocCenter,1);
            TCO_T       = SD_Trans(TCO,-Mode_Center);
            
            % Rotate the molecule
            Phi_r   = GUI_Inputs.Phi/180*pi;
            Psi_r   = GUI_Inputs.Psi/180*pi;
            Theta_r = GUI_Inputs.Theta/180*pi;
            R1      = R1_ZYZ_0(Phi_r,Psi_r,Theta_r);
            TCO_R   = SD_Rot(TCO_T,R1);
            
            % Add figure drawing function and GUI inputs
            TCO_R.hPlotFunc  = @PlotXYZfiles_Acid;
            TCO_R.GUI_Inputs = app.Parse_GUI;
            
            % update StructureData to GUIs
            app.Structure = TCO_R;
            Export2Parent(app)
        end

        % Button pushed function: Button_Draw
        function Button_DrawPushed(app, event)
            app.Structure.SD_Draw;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Model_TCO';
            app.Panel_Sturcture = uipanel(app.UIFigure);
            app.Panel_Sturcture.Title = 'Orientation';
            app.UITable = uitable(app.Panel_Sturcture);
            app.UITable.ColumnName = {''; 'Phi'; 'Psi'; 'Theta'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.OverallrotPhiLabel = uilabel(app.Panel_Sturcture);
            app.OverallrotPhiLabel.Text = 'Overall rot.: Phi';
            app.Phi = uieditfield(app.Panel_Sturcture, 'numeric');
            app.PsiLabel = uilabel(app.Panel_Sturcture);
            app.PsiLabel.Text = 'Psi';
            app.Psi = uieditfield(app.Panel_Sturcture, 'numeric');
            app.ThetaLabel = uilabel(app.Panel_Sturcture);
            app.ThetaLabel.Text = 'Theta';
            app.Theta = uieditfield(app.Panel_Sturcture, 'numeric');
            app.Theta.Value = 45;
            app.Panel_FigOpt = uipanel(app.UIFigure);
            app.Panel_FigOpt.Title = 'Figure options';
            app.CheckBox_Atom = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Atom.Text = 'Atom';
            app.CheckBox_Atom.Value = true;
            app.CheckBox_Bond = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Bond.Text = 'Bond';
            app.CheckBox_Bond.Value = true;
            app.CheckBox_Axis = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Axis.Text = 'Axis';
            app.CheckBox_Axis.Value = true;
            app.Button_Generate = uibutton(app.UIFigure, 'push');
            app.Button_Generate.ButtonPushedFcn = createCallbackFcn(app, @Button_GeneratePushed, true);
            app.Button_Generate.Text = 'Generate';
            app.Button_Draw = uibutton(app.UIFigure, 'push');
            app.Button_Draw.ButtonPushedFcn = createCallbackFcn(app, @Button_DrawPushed, true);
            app.Button_Draw.Text = 'Draw';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportDataPushed, true);
            app.Button_ExportData.Text = 'Export Data';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'V 1.3';
            app.Panel_Freq = uipanel(app.UIFigure);
            app.Panel_Freq.Title = 'Local mode (cm-1)';
            app.LabeledFreqcm1 = uilabel(app.Panel_Freq);
            app.LabeledFreqcm1.Text = 'Labeled Freq. (cm-1)';
            app.LabelFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.LabelFreq.Value = 1630;
            app.LabelIndex = uilabel(app.Panel_Freq);
            app.LabelIndex.Text = 'Label Index:';
            app.LabelInd = uieditfield(app.Panel_Freq, 'text');
            app.ModeFreqcm1Label = uilabel(app.Panel_Freq);
            app.ModeFreqcm1Label.Text = 'Mode Freq. (cm-1)';
            app.LocFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.LocFreq.Value = 1650;
            app.FreqModificationLabel = uilabel(app.Panel_Freq);
            app.FreqModificationLabel.Text = 'Freq. Modification';
            app.DropDown_LocFreq = uidropdown(app.Panel_Freq);
            app.DropDown_LocFreq.Items = {'None', 'Jensen Map'};
            app.DropDown_LocFreq.Value = 'None';
            app.Anharmonicitycm1Label = uilabel(app.Panel_Freq);
            app.Anharmonicitycm1Label.Text = 'Anharmonicity (cm-1)';
            app.Anharm = uieditfield(app.Panel_Freq, 'numeric');
            app.Anharm.Value = 10;
            app.Panel_Coupling = uipanel(app.UIFigure);
            app.Panel_Coupling.Title = 'Coupling Model';
            app.ModelLabel = uilabel(app.Panel_Coupling);
            app.ModelLabel.Text = 'Model';
            app.DropDown_Coupling = uidropdown(app.Panel_Coupling);
            app.DropDown_Coupling.Items = {'TDC', 'NN + TDC', 'Jensen Map + TDC', 'Zero'};
            app.DropDown_Coupling.Value = 'TDC';
            app.BetaNNcm1Label = uilabel(app.Panel_Coupling);
            app.BetaNNcm1Label.Text = 'Beta NN (cm-1)';
            app.EditField_NN = uieditfield(app.Panel_Coupling, 'numeric');
            app.EditField_NN.Value = 0.8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_TCO(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end