classdef Model_TwoDGrid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_Orientation               matlab.ui.container.Panel
        FrameselectionConventionDropDownLabel  matlab.ui.control.Label
        Frame_Type                      matlab.ui.control.DropDown
        CenterIndexEditFieldLabel       matlab.ui.control.Label
        MF_Center                       matlab.ui.control.NumericEditField
        Z_iIndexLabel                   matlab.ui.control.Label
        MF_Zi                           matlab.ui.control.NumericEditField
        Z_fIndexEditFieldLabel          matlab.ui.control.Label
        MF_Zf                           matlab.ui.control.NumericEditField
        XZ_iIndexEditFieldLabel         matlab.ui.control.Label
        MF_XZi                          matlab.ui.control.NumericEditField
        XZ_fIndexEditFieldLabel         matlab.ui.control.Label
        MF_XZf                          matlab.ui.control.NumericEditField
        DegLabel                        matlab.ui.control.Label
        PhiEditFieldLabel               matlab.ui.control.Label
        LF_Phi                          matlab.ui.control.NumericEditField
        PsiEditFieldLabel               matlab.ui.control.Label
        LF_Psi                          matlab.ui.control.NumericEditField
        ThetaEditFieldLabel             matlab.ui.control.Label
        LF_Theta                        matlab.ui.control.NumericEditField
        Panel_Grid                      matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        Panel_FigOpt                    matlab.ui.container.Panel
        CheckBox_Atom                   matlab.ui.control.CheckBox
        CheckBox_Bond                   matlab.ui.control.CheckBox
        CheckBox_Axis                   matlab.ui.control.CheckBox
        CheckBox_Lattice                matlab.ui.control.CheckBox
        CheckBox_AtomIndex              matlab.ui.control.CheckBox
        Version                         matlab.ui.control.Label
        Button_Generate                 matlab.ui.control.Button
        Button_Draw                     matlab.ui.control.Button
        Button_ExportData               matlab.ui.control.Button
        Button_LoadInput                matlab.ui.control.Button
        Panel_Freq                      matlab.ui.container.Panel
        LocalmodeFreqEditFieldLabel     matlab.ui.control.Label
        LocFreq                         matlab.ui.control.NumericEditField
        LabeledFreq                     matlab.ui.control.Label
        LabelFreq                       matlab.ui.control.NumericEditField
        AnharmonicityLabel              matlab.ui.control.Label
        Anharm                          matlab.ui.control.NumericEditField
        LabelIndex                      matlab.ui.control.Label
        LabelInd                        matlab.ui.control.EditField
        DiagonalDisorderEditFieldLabel  matlab.ui.control.Label
        DiagDisorder                    matlab.ui.control.NumericEditField
        OffDiagonalDisorderEditFieldLabel  matlab.ui.control.Label
        OffDiagDisorder                 matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        Parent    % handles of parent
        Structure % molecule structre class % Description
        CombOrder % The sequence number order from comb2
        GUI_Tag   % Tag for GUI recognition 
        G09       % G09 raw data
        PDB       % Organized PDB info
    end

    methods (Access = public)
    
        function Init(app)
            app.GUI_Tag = '2DGrid';
            
            Name = {'Vec.1';'Vec.2'};
            Orientation = [5.1,0,0,4;-5.54,11.54,0,2];
            Data_Table = [Name,num2cell(Orientation)];
            
            ColumnName     = {'Vec.',   'X',   'Y',    'Z','# Mol.'};
            ColumnFormat   = {'char','bank','bank', 'bank',  'bank'};
            ColumnEditable = [ false,  true,  true,   true,   true];
            ColumnWidth    = {    37,    45,    45,     45,     40};
            
            app.UITable.Data           = Data_Table;
            app.UITable.ColumnName     = ColumnName;
            app.UITable.ColumnFormat   = ColumnFormat;
            app.UITable.ColumnEditable = ColumnEditable;
            app.UITable.ColumnWidth    = ColumnWidth;
            % app.UITable.RowName        = 'numbered';
            
            % update structure and the file name on GUI
            if ~isempty(app.CombOrder)
                app.UIFigure.Name = ['#',int2str(app.CombOrder),': '];
            else
                app.UIFigure.Name = 'Model 2D-Grid';
            end
            
            %% Add Properties Change Listner
            SA = [...
                  app.Frame_Type,...
                  app.MF_Center,...
                  app.MF_Zi,...
                  app.MF_Zf,...
                  app.MF_XZi,...
                  app.MF_XZf,...
                  app.LF_Phi,...
                  app.LF_Psi,...
                  app.LF_Theta,...
                  app.CheckBox_Atom,...
                  app.CheckBox_Bond,...
                  app.CheckBox_Axis,...
                  app.CheckBox_Lattice,...
                  app.CheckBox_AtomIndex,...
                  app.LocFreq,...
                  app.LabelFreq,...
                  app.LabelInd,...
                  app.Anharm,...
                  app.DiagDisorder,...
                  app.OffDiagDisorder,...
                  ];
            
            for i = 1:length(SA)
                SA(i).ValueChangedFcn = @(~,~)RefreshSD(app);
            end
            
            app.UITable.CellEditCallback = @(~,~)RefreshSD(app);

        end
        
        function O = Parse_GUI(app)
            % For Monomer --------------------------------
            O.Frame_Type = app.Frame_Type.Value; 
            O.MF_Center  = app.MF_Center.Value;
            O.MF_Zi      = app.MF_Zi.Value;   
            O.MF_Zf      = app.MF_Zf.Value;
            O.MF_XZi     = app.MF_XZi.Value;
            O.MF_XZf     = app.MF_XZf.Value;
            O.LF_Phi     = app.LF_Phi.Value;
            O.LF_Psi     = app.LF_Psi.Value;
            O.LF_Theta   = app.LF_Theta.Value;
            % --------------------------------------------
            
            % For 2D Grid --------------------------------
            Data_Table = app.UITable.Data;
            O.Vec_1    = cell2mat(Data_Table(1,2:4));     
            O.Vec_2    = cell2mat(Data_Table(2,2:4));     
            O.N_1      = Data_Table{1,5};
            O.N_2      = Data_Table{2,5};
            % --------------------------------------------
            
            % For Local Mode frequencies ----------------------
            O.NLFreq          = app.LocFreq.Value;
            O.LFreq           = app.LabelFreq.Value;
            O.Anharm          = app.Anharm.Value;
            O.L_Index         = str2num(app.LabelInd.Value);
            O.DiagDisorder    = app.DiagDisorder.Value;
            O.OffDiagDisorder = app.OffDiagDisorder.Value;
            % -------------------------------------------------
            
            % For molecule ploting ----------------------------
            O.Plot_Atoms     = app.CheckBox_Atom.Value;
            O.Plot_Bonds     = app.CheckBox_Bond.Value;
            O.Plot_Axis      = app.CheckBox_Axis.Value;
            O.Plot_Lattice   = app.CheckBox_Lattice.Value;
            O.Plot_AtomIndex = app.CheckBox_AtomIndex.Value;
            % -------------------------------------------------
        end
        
        function RefreshSD(app)
            Button_GeneratePushed(app,'')
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                ParentInfo = varargin;
            else
                ParentInfo = {'StandAlong'};
            end
            AssignParent(app,ParentInfo)
            
            % initialize the orientation table
            Init(app)
        end

        % Button pushed function: Button_LoadInput
        function Button_LoadInputPushed(app, event)
            PWD = pwd;
            G09_default_folder = [PWD, '/StructureFiles/G09/'];
            
            [FilesName,PathName,~] = uigetfile({'*.txt','Formatted G09 output'; ...
                                                '*,*','All Files'},...
                                                'Select inputs',G09_default_folder);
                
            disp([FilesName,' loaded...'])
            G09_Path = [PathName FilesName];                             
            S_G09_MF = ReadG09Input(G09_Path);
            
            % Add function handles and GUI_Inputs for plotting
            S_G09_MF.hPlotFunc  = @PlotXYZ_Grid;
            S_G09_MF.GUI_Inputs = app.Parse_GUI;
            app.G09 = S_G09_MF;
            
            % update structure and the file name on GUI
            if ~isempty(app.CombOrder)
                app.UIFigure.Name = ['#',int2str(app.CombOrder),': ',FilesName];
            else
                app.UIFigure.Name = FilesName;
            end
            
            % update G09 info onto GUI
            app.MF_Center.Value = S_G09_MF.Extra.Mol_Frame.Center_Ind;
            app.MF_Zi.Value     = S_G09_MF.Extra.Mol_Frame.Z_i_Ind;
            app.MF_Zf.Value     = S_G09_MF.Extra.Mol_Frame.Z_f_Ind;
            app.MF_XZi.Value    = S_G09_MF.Extra.Mol_Frame.XZ_i_Ind;
            app.MF_XZf.Value    = S_G09_MF.Extra.Mol_Frame.XZ_f_Ind;
            app.LocFreq.Value   = S_G09_MF.LocFreq;
            app.Anharm.Value    = S_G09_MF.LocAnharm;
            
            Button_GeneratePushed(app, event)
        end

        % Button pushed function: Button_Generate
        function Button_GeneratePushed(app, event)
            if isempty(app.G09)
                Button_LoadInputPushed(app, event)
            end
            
            Monomer = app.G09;
            Monomer.GUI_Inputs = app.Parse_GUI; % update GUI_Inputs
            
            S_Monomer_LF   = R_MF2LF(Monomer);
            Structure_Grid = ConstructGrid(S_Monomer_LF);
            Structure_Grid.StructModel = 3; % number for "StructureModel.m" to run
            
            % export necessary handle and functions
            Structure_Grid.hPlotFunc     = @PlotXYZ_Grid;
            
            
            % update to COSMOSS
            app.Structure = Structure_Grid;
            Export2Parent(app)
        end

        % Button pushed function: Button_Draw
        function Button_DrawPushed(app, event)
            if isempty(app.Structure)
                Button_GeneratePushed(app, event)
            end
            app.Structure.Draw;
        end

        % Button pushed function: Button_ExportData
        function Button_ExportDataPushed(app, event)
            ExportGUIData(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Model 2D-Grid';
            app.Panel_Orientation = uipanel(app.UIFigure);
            app.Panel_Orientation.Title = 'Molecular orientation parameters';
            app.FrameselectionConventionDropDownLabel = uilabel(app.Panel_Orientation);
            app.FrameselectionConventionDropDownLabel.Text = 'Frame selection Convention:';
            app.Frame_Type = uidropdown(app.Panel_Orientation);
            app.Frame_Type.Items = {'XZ', 'YZ'};
            app.Frame_Type.Value = 'XZ';
            app.CenterIndexEditFieldLabel = uilabel(app.Panel_Orientation);
            app.CenterIndexEditFieldLabel.Text = 'Center Index';
            app.MF_Center = uieditfield(app.Panel_Orientation, 'numeric');
            app.Z_iIndexLabel = uilabel(app.Panel_Orientation);
            app.Z_iIndexLabel.Text = 'Z_i Index';
            app.MF_Zi = uieditfield(app.Panel_Orientation, 'numeric');
            app.Z_fIndexEditFieldLabel = uilabel(app.Panel_Orientation);
            app.Z_fIndexEditFieldLabel.Text = 'Z_f Index';
            app.MF_Zf = uieditfield(app.Panel_Orientation, 'numeric');
            app.XZ_iIndexEditFieldLabel = uilabel(app.Panel_Orientation);
            app.XZ_iIndexEditFieldLabel.Text = 'XZ_i Index';
            app.MF_XZi = uieditfield(app.Panel_Orientation, 'numeric');
            app.XZ_fIndexEditFieldLabel = uilabel(app.Panel_Orientation);
            app.XZ_fIndexEditFieldLabel.Text = 'XZ_f Index';
            app.MF_XZf = uieditfield(app.Panel_Orientation, 'numeric');
            app.DegLabel = uilabel(app.Panel_Orientation);
            app.DegLabel.Text = 'Deg.';
            app.PhiEditFieldLabel = uilabel(app.Panel_Orientation);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.LF_Phi = uieditfield(app.Panel_Orientation, 'numeric');
            app.PsiEditFieldLabel = uilabel(app.Panel_Orientation);
            app.PsiEditFieldLabel.Text = 'Psi';
            app.LF_Psi = uieditfield(app.Panel_Orientation, 'numeric');
            app.ThetaEditFieldLabel = uilabel(app.Panel_Orientation);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.LF_Theta = uieditfield(app.Panel_Orientation, 'numeric');
            app.Panel_Grid = uipanel(app.UIFigure);
            app.Panel_Grid.Title = 'Two-dimensional grid parameters';
            app.UITable = uitable(app.Panel_Grid);
            app.UITable.ColumnName = {'Vec.'; 'X'; 'Y'; 'Z'; '# Mol.'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true];
            app.Panel_FigOpt = uipanel(app.UIFigure);
            app.Panel_FigOpt.Title = 'Figure options';
            app.CheckBox_Atom = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Atom.Text = 'Atom';
            app.CheckBox_Atom.Value = true;
            app.CheckBox_Bond = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Bond.Text = 'Bond';
            app.CheckBox_Bond.Value = true;
            app.CheckBox_Axis = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Axis.Text = 'Axis';
            app.CheckBox_Lattice = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_Lattice.Text = 'Lattice';
            app.CheckBox_Lattice.Value = true;
            app.CheckBox_AtomIndex = uicheckbox(app.Panel_FigOpt);
            app.CheckBox_AtomIndex.Text = 'Atom Index';
            app.Version = uilabel(app.Panel_FigOpt);
            app.Version.Text = 'V1.2';
            app.Button_Generate = uibutton(app.UIFigure, 'push');
            app.Button_Generate.ButtonPushedFcn = createCallbackFcn(app, @Button_GeneratePushed, true);
            app.Button_Generate.Text = 'Generate';
            app.Button_Draw = uibutton(app.UIFigure, 'push');
            app.Button_Draw.ButtonPushedFcn = createCallbackFcn(app, @Button_DrawPushed, true);
            app.Button_Draw.Text = 'Draw';
            app.Button_ExportData = uibutton(app.UIFigure, 'push');
            app.Button_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportDataPushed, true);
            app.Button_ExportData.Text = 'Export Data';
            app.Button_LoadInput = uibutton(app.UIFigure, 'push');
            app.Button_LoadInput.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadInputPushed, true);
            app.Button_LoadInput.Text = 'Load Input';
            app.Panel_Freq = uipanel(app.UIFigure);
            app.Panel_Freq.Title = 'Freq. & Anharmonicy (cm-1)';
            app.LocalmodeFreqEditFieldLabel = uilabel(app.Panel_Freq);
            app.LocalmodeFreqEditFieldLabel.Text = 'Local mode Freq.:';
            app.LocFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.LocFreq.Value = 1650;
            app.LabeledFreq = uilabel(app.Panel_Freq);
            app.LabeledFreq.Text = 'Labeled Freq.:';
            app.LabelFreq = uieditfield(app.Panel_Freq, 'numeric');
            app.LabelFreq.Value = 1630;
            app.AnharmonicityLabel = uilabel(app.Panel_Freq);
            app.AnharmonicityLabel.Text = 'Anharmonicity:';
            app.Anharm = uieditfield(app.Panel_Freq, 'numeric');
            app.Anharm.Value = 10;
            app.LabelIndex = uilabel(app.Panel_Freq);
            app.LabelIndex.Text = 'Label Index:';
            app.LabelInd = uieditfield(app.Panel_Freq, 'text');
            app.DiagonalDisorderEditFieldLabel = uilabel(app.Panel_Freq);
            app.DiagonalDisorderEditFieldLabel.Text = 'Diagonal Disorder:';
            app.DiagDisorder = uieditfield(app.Panel_Freq, 'numeric');
            app.OffDiagonalDisorderEditFieldLabel = uilabel(app.Panel_Freq);
            app.OffDiagonalDisorderEditFieldLabel.Text = 'Off-Diagonal Disorder:';
            app.OffDiagDisorder = uieditfield(app.Panel_Freq, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Model_TwoDGrid(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end