classdef OrientationAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UITable              matlab.ui.control.Table
        IntensityPlotButton  matlab.ui.control.Button
        ExportDataButton     matlab.ui.control.Button
        ReadBrushButton      matlab.ui.control.Button
    end


    properties (Access = public)
        Parent % handles of parent
        Data_2DSFG % StructureData Class  % Description
        CurrentFig
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % if called from other GUI, assign parent handle
            if nargin > 1
                AssignParent(app,varargin)
                app.Data_2DSFG = app.Parent.Data_2DSFG;
            else
                disp('Running Orientation Analysis in the stand alone mode...')
            end
            
            % initialize the orientation table
            %Init_TCO(app)
            
        end

        % Button pushed function: IntensityPlotButton
        function IntensityPlotButtonPushed(app, event)
            app.Data_2DSFG = app.Parent.Data_2DSFG;
            Int_SPM = app.Data_2DSFG.Response.Int.Int_SPM;
            
            COSMOSS_Input = Parse_COSMOSS(app.Parent);
            CVL = Conv2D(Int_SPM,COSMOSS_Input);
            CVL.FilesName = 'Response intensity plot'; % pass filesname for figure title
            
            app.CurrentFig = figure;
            hAx = axes('Parent',app.CurrentFig);
            Plot2D(hAx,CVL,COSMOSS_Input,'2DSFG Int');
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            Data_Orient.Data_2DSFG = app.Data_2DSFG;
            assignin('base','Data_Orient',Data_Orient)
            disp('Updated Data_Orient exported!')
        end

        % Button pushed function: ReadBrushButton
        function ReadBrushButtonPushed(app, event)
            hF = app.CurrentFig;
            hScatter = findobj(hF,'Type','Scatter');
            
            brushedIdx = logical(hScatter.BrushData);
            Freq = [hScatter.XData(brushedIdx)',hScatter.YData(brushedIdx)'];
            app.UITable.Data = Freq;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Pump F.'; 'Prob F.'};
            app.UITable.RowName = {};
            app.IntensityPlotButton = uibutton(app.UIFigure, 'push');
            app.IntensityPlotButton.ButtonPushedFcn = createCallbackFcn(app, @IntensityPlotButtonPushed, true);
            app.IntensityPlotButton.Text = 'Intensity Plot';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ReadBrushButton = uibutton(app.UIFigure, 'push');
            app.ReadBrushButton.ButtonPushedFcn = createCallbackFcn(app, @ReadBrushButtonPushed, true);
            app.ReadBrushButton.Text = 'Read Brush';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OrientationAnalysis(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end